classdef AL_Visualizer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        note                       matlab.ui.control.Label
        next                       matlab.ui.control.Button
        previous                   matlab.ui.control.Button
        reset                      matlab.ui.control.Button
        footer                     matlab.ui.control.Label
        OptimizationProblem        matlab.ui.container.Panel
        eqn_message                matlab.ui.control.HTML
        y7                         matlab.ui.control.NumericEditField
        x7                         matlab.ui.control.NumericEditField
        y6                         matlab.ui.control.NumericEditField
        x6                         matlab.ui.control.NumericEditField
        y5                         matlab.ui.control.NumericEditField
        x5                         matlab.ui.control.NumericEditField
        y4                         matlab.ui.control.NumericEditField
        x4                         matlab.ui.control.NumericEditField
        y3                         matlab.ui.control.NumericEditField
        x3                         matlab.ui.control.NumericEditField
        y2                         matlab.ui.control.NumericEditField
        x2                         matlab.ui.control.NumericEditField
        y1                         matlab.ui.control.NumericEditField
        x1                         matlab.ui.control.NumericEditField
        y_header                   matlab.ui.control.Label
        AddPointButton             matlab.ui.control.Button
        RemoveLastPointButton      matlab.ui.control.Button
        x_header                   matlab.ui.control.Label
        objective_input_label      matlab.ui.control.Label
        objective_input            matlab.ui.control.EditField
        points_header              matlab.ui.control.Label
        objective_header           matlab.ui.control.Label
        step_panel                 matlab.ui.container.Panel
        objective_display          matlab.ui.control.Label
        penalty_display            matlab.ui.control.Label
        subproblem_number_display  matlab.ui.control.Label
        Subproblem_axes            matlab.ui.control.UIAxes
        LM_axes                    matlab.ui.control.UIAxes
        Original_axes              matlab.ui.control.UIAxes
    end

    properties (Access=private)
        % the points defining the design domain, defaults below
        points=[-1 -4.5;
        -5 -4;
        -5.5 4;
        -1 5];
        % the step in the optimization (zero means inputs are editable)
        step=0;
        % Lagrange Multipliers
        LM_history=[];
        % penalty parameter
        mu=1;
        % penalty parameter increase factor
        mu_inc=10;
        mu_init=1;
        mu_limit=1e6;

        % design points
        guess_history=[];
        obj_history=[];

        min_pts=3;
        max_pts=7;
        limits=[];
        constraint_vals=[];
    end
    methods (Access = private)
        function update_displays(app)
            plot_all(app.Original_axes, app.Subproblem_axes, app.LM_axes, ...
                app.limits, ...
                app.guess_history(:,end),app.guess_history(:,end-1), ...
                app.step, app.constraint_vals,app.LM_history(:,end), ...
                app.mu,app.LM_history);
            app.penalty_display.Text="μ = "+num2str(app.mu);
            app.subproblem_number_display.Text="Step Number = "+...
                num2str(app.step);
            app.objective_display.Text="f = "+num2str(app.obj_history(end));
        end
        function advance_step(app)
            app.mu=min(app.mu*app.mu_inc,app.mu_limit);
            [x, new_lambda, obj] = AL_next_step(app.guess_history(:,end), ...
                app.constraint_vals, app.LM_history(:,end), app.mu);
            app.step=app.step+1;
            app.guess_history=[app.guess_history x];
            app.LM_history=[app.LM_history new_lambda];
            app.obj_history=[app.obj_history obj];
            
            app.update_displays();
        end

        function reset_steps(app)
            app.objective_input.Editable="on";
            app.x1.Editable="on";
            app.y1.Editable="on";
            app.x2.Editable="on";
            app.y2.Editable="on";
            app.x3.Editable="on";
            app.y3.Editable="on";
            app.x4.Editable="on";
            app.y4.Editable="on";
            app.x5.Editable="on";
            app.y5.Editable="on";
            app.x6.Editable="on";
            app.y6.Editable="on";
            app.x7.Editable="on";
            app.y7.Editable="on";

            app.RemoveLastPointButton.Visible="on";
            app.AddPointButton.Visible="on";
            app.reset.Visible="off";
            app.previous.Visible="off";

            app.step=0;

            % update the plot of objective function
            app.update_objective_plot();

            cla(app.Subproblem_axes,'reset');
            cla(app.LM_axes,'reset');

            app.penalty_display.Text="μ = ";
            app.subproblem_number_display.Text="Step Number = ";
            app.objective_display.Text="f = ";
        end

        function back_step(app)
            app.LM_history=app.LM_history(:,1:end-1);
            app.guess_history=app.guess_history(:,1:end-1);
            app.obj_history=app.obj_history(1:end-1);
            app.step=app.step-1;
            app.mu=app.mu/app.mu_inc;
            app.update_displays();
        end

        function add_row(app)
            app.points=[app.points;0,0];
            app.update_rows();
        end

        function remove_row(app)
            app.points=app.points(1:end-1,:);
            app.update_rows();
        end
        function update_rows(app)
            pts_count=size(app.points,1);
            if (pts_count>=1)
                app.x1.Value=app.points(1,1);
                app.x1.Visible="on";
                app.y1.Value=app.points(1,2);
                app.y1.Visible="on";
            else
                app.x1.Visible="off";
                app.y1.Visible="off";
            end

            if (pts_count>=2)
                app.x2.Value=app.points(2,1);
                app.x2.Visible="on";
                app.y2.Value=app.points(2,2);
                app.y2.Visible="on";
            else
                app.x2.Visible="off";
                app.y2.Visible="off";
            end

            if (pts_count>=3)
                app.x3.Value=app.points(3,1);
                app.x3.Visible="on";
                app.y3.Value=app.points(3,2);
                app.y3.Visible="on";
            else
                app.x3.Visible="off";
                app.y3.Visible="off";
            end

            if (pts_count>=4)
                app.x4.Value=app.points(4,1);
                app.x4.Visible="on";
                app.y4.Value=app.points(4,2);
                app.y4.Visible="on";
            else
                app.x4.Visible="off";
                app.y4.Visible="off";
            end

            if (pts_count>=5)
                app.x5.Value=app.points(5,1);
                app.x5.Visible="on";
                app.y5.Value=app.points(5,2);
                app.y5.Visible="on";
            else
                app.x5.Visible="off";
                app.y5.Visible="off";
            end

            if (pts_count>=6)
                app.x6.Value=app.points(6,1);
                app.x6.Visible="on";
                app.y6.Value=app.points(6,2);
                app.y6.Visible="on";
            else
                app.x6.Visible="off";
                app.y6.Visible="off";
            end

            if (pts_count>=7)
                app.x7.Value=app.points(7,1);
                app.x7.Visible="on";
                app.y7.Value=app.points(7,2);
                app.y7.Visible="on";
            else
                app.x7.Visible="off";
                app.y7.Visible="off";
            end
        end
        function update_objective_plot(app)
            app.limits=get_limits(app.points);
            app.constraint_vals=get_constraints(app.points);

            cla(app.Original_axes,'reset');
            hold(app.Original_axes, 'on');
            objective_plot(app.Original_axes,app.step,app.limits,app.constraint_vals,0,[],[]);
            hold(app.Original_axes, 'off');
        end
        function reset_obj_eqn(app)
            start_eqn='(x/2)^2 + (y/15+4)^2-sin(x)';
            % Generate Equation m-file
            fid = fopen ('obj_equation.m','w');
            fprintf(fid,'function f = obj_equation(x,y) \n');
            fprintf(fid,['    f=',start_eqn,';\n']);
            fprintf(fid,'end \n');
            fclose (fid);
        end
        function catcher(app)
            app.eqn_message.Visible="on";
            app.reset_obj_eqn();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PolygonFit_OpeningFcn(app, varargin)
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, "center");

            app.reset_obj_eqn();
            
            % set the initial design domain points
            app.update_rows();

            app.reset_steps();
             
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to PolygonFit (see VARARGIN)
            
            % Choose default command line output for PolygonFit
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: next
        function nextButtonPushed(app, event)
            %% do whatever it is to advance to next step
            if (app.step==0)
                app.objective_input.Editable="off";
                app.x1.Editable="off";
                app.y1.Editable="off";
                app.x2.Editable="off";
                app.y2.Editable="off";
                app.x3.Editable="off";
                app.y3.Editable="off";
                app.x4.Editable="off";
                app.y4.Editable="off";
                app.x5.Editable="off";
                app.y5.Editable="off";
                app.x6.Editable="off";
                app.y6.Editable="off";
                app.x7.Editable="off";
                app.y7.Editable="off";
                app.RemoveLastPointButton.Visible="off";
                app.AddPointButton.Visible="off";
                app.reset.Visible="on";
                app.previous.Visible="on";

                app.LM_history=zeros(size(app.points,1),1);
            
                app.guess_history=[mean(app.points(:,1)); mean(app.points(:,2))];

                app.obj_history=[obj_wrapper(app.guess_history)];

                app.limits=get_limits(app.points);

                app.mu=app.mu_init;
            end
            app.advance_step();
        end

        % Callback function
        function ptsCellEdit(app, event)

        end

        % Value changed function: x1
        function x1_changed(app, event)
            app.points(1,2) = app.x1.Value;
            app.update_objective_plot();
        end

        % Value changed function: y1
        function y1_changed(app, event)
            app.points(1,1) = app.y1.Value;
            app.update_objective_plot();
        end

        % Value changed function: objective_input
        function objective_changed(app, event)
            obj_str = app.objective_input.Value;
            
            try
                app.eqn_message.Visible="off";
                f = str2sym(obj_str);

                % Generate Equation m-file
                f_string = char(f);
                fid = fopen ('obj_equation.m','w');
                fprintf(fid,'function f = obj_equation(x,y) \n');
                fprintf(fid,['    f=',f_string,';\n']);
                fprintf(fid,'end \n');
                fclose (fid);
                tests_vals=linspace(-1000,1000,5);
                for i=tests_vals
                    x=obj_equation(i,i);
                    if ~isreal(x)
                        app.catcher();
                    end
                end
            catch
                app.catcher();
            end
            
            app.update_objective_plot();
        end

        % Button pushed function: previous
        function previousButtonPushed(app, event)
            if (app.step==1)
                app.reset_steps();
            else
                app.back_step();
            end
        end

        % Value changed function: x2
        function x2_changed(app, event)
            app.points(2,1) = app.x2.Value;
            app.update_objective_plot();
        end

        % Button pushed function: RemoveLastPointButton
        function remove_pt(app, event)
            pts_count=size(app.points,1);
            if (pts_count==app.min_pts+1)
                app.RemoveLastPointButton.Visible='off';
            end
            if (pts_count==app.max_pts)
                app.AddPointButton.Visible='on';
            end
            if (pts_count>app.min_pts)
                app.remove_row();
            end
            app.update_objective_plot();
        end

        % Button pushed function: AddPointButton
        function add_pt(app, event)
            pts_count=size(app.points,1);
            if (pts_count==app.min_pts)
                app.RemoveLastPointButton.Visible='on';
            end
            if (pts_count==app.max_pts-1)
                app.AddPointButton.Visible='off';
            end
            if (pts_count<app.max_pts)
                app.add_row();
            end
            app.update_objective_plot();
        end

        % Value changed function: y2
        function y2_changed(app, event)
            app.points(2,2)  = app.y2.Value;
            app.update_objective_plot();
        end

        % Value changed function: x3
        function x3_changed(app, event)
            app.points(3,1) = app.x3.Value;
            app.update_objective_plot();
        end

        % Value changed function: y3
        function y3_changed(app, event)
            app.points(3,2) = app.y3.Value;
            app.update_objective_plot();
        end

        % Value changed function: x4
        function x4_changed(app, event)
            app.points(4,1) = app.x4.Value;
            app.update_objective_plot();
        end

        % Value changed function: y4
        function y4_changed(app, event)
            app.points(4,2) = app.y4.Value;
            app.update_objective_plot();
        end

        % Value changed function: x5
        function x5_changed(app, event)
            app.points(5,1) = app.x5.Value;
            app.update_objective_plot();
        end

        % Value changed function: y5
        function y5_changed(app, event)
            app.points(5,2) = app.y5.Value;
            app.update_objective_plot();
        end

        % Value changed function: x6
        function x6_changed(app, event)
            app.points(6,1) = app.x6.Value;
            app.update_objective_plot();
        end

        % Value changed function: y6
        function y6_changed(app, event)
            app.points(6,2) = app.y6.Value;
            app.update_objective_plot();
        end

        % Value changed function: x7
        function x7_changed(app, event)
            app.points(7,1) = app.x7.Value;
            app.update_objective_plot();
        end

        % Value changed function: y7
        function y7_changed(app, event)
            app.points(7,2) = app.y7.Value;
            app.update_objective_plot();
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            app.reset_steps();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'AL_Visualizer';
            app.figure1.Tag = 'figure1';
            app.Original_axes = uiaxes(app.figure1);
            app.Original_axes.Tag = 'axes1';
            app.LM_axes = uiaxes(app.figure1);
            app.LM_axes.Tag = 'axes3';
            app.Subproblem_axes = uiaxes(app.figure1);
            app.Subproblem_axes.Tag = 'axes1';
            app.step_panel = uipanel(app.figure1);
            app.subproblem_number_display = uilabel(app.step_panel);
            app.subproblem_number_display.Tag = 'text10';
            app.subproblem_number_display.Text = 'Step Number  =';
            app.penalty_display = uilabel(app.step_panel);
            app.penalty_display.Tag = 'text7';
            app.penalty_display.Text = 'μ =';
            app.objective_display = uilabel(app.step_panel);
            app.objective_display.Tag = 'text7';
            app.objective_display.Text = 'f =';
            app.OptimizationProblem = uipanel(app.figure1);
            app.OptimizationProblem.Title = 'Optimization Problem';
            app.OptimizationProblem.Tag = 'uipanel1';
            app.objective_header = uilabel(app.OptimizationProblem);
            app.objective_header.Tag = 'text3';
            app.objective_header.Text = 'Minimize this function: ';
            app.points_header = uilabel(app.OptimizationProblem);
            app.points_header.Tag = 'text5';
            app.points_header.Text = 'inside the convex hull of the polygon described by this sequence of points:';
            app.objective_input = uieditfield(app.OptimizationProblem, 'text');
            app.objective_input.ValueChangedFcn = createCallbackFcn(app, @objective_changed, true);
            app.objective_input.Tag = 'edit2';
            app.objective_input.Value = '(x/2)^2 + (y/15+4)^2-sin(x)';
            app.objective_input_label = uilabel(app.OptimizationProblem);
            app.objective_input_label.Tag = 'text13';
            app.objective_input_label.Text = 'f =';
            app.x_header = uilabel(app.OptimizationProblem);
            app.x_header.Tag = 'text5';
            app.x_header.Text = 'X:';
            app.RemoveLastPointButton = uibutton(app.OptimizationProblem, 'push');
            app.RemoveLastPointButton.ButtonPushedFcn = createCallbackFcn(app, @remove_pt, true);
            app.RemoveLastPointButton.Text = 'Remove Last Point';
            app.AddPointButton = uibutton(app.OptimizationProblem, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @add_pt, true);
            app.AddPointButton.Text = 'Add Point';
            app.y_header = uilabel(app.OptimizationProblem);
            app.y_header.Tag = 'text5';
            app.y_header.Text = 'Y:';
            app.x1 = uieditfield(app.OptimizationProblem, 'numeric');
            app.x1.ValueChangedFcn = createCallbackFcn(app, @x1_changed, true);
            app.y1 = uieditfield(app.OptimizationProblem, 'numeric');
            app.y1.ValueChangedFcn = createCallbackFcn(app, @y1_changed, true);
            app.x2 = uieditfield(app.OptimizationProblem, 'numeric');
            app.x2.ValueChangedFcn = createCallbackFcn(app, @x2_changed, true);
            app.y2 = uieditfield(app.OptimizationProblem, 'numeric');
            app.y2.ValueChangedFcn = createCallbackFcn(app, @y2_changed, true);
            app.x3 = uieditfield(app.OptimizationProblem, 'numeric');
            app.x3.ValueChangedFcn = createCallbackFcn(app, @x3_changed, true);
            app.y3 = uieditfield(app.OptimizationProblem, 'numeric');
            app.y3.ValueChangedFcn = createCallbackFcn(app, @y3_changed, true);
            app.x4 = uieditfield(app.OptimizationProblem, 'numeric');
            app.x4.ValueChangedFcn = createCallbackFcn(app, @x4_changed, true);
            app.y4 = uieditfield(app.OptimizationProblem, 'numeric');
            app.y4.ValueChangedFcn = createCallbackFcn(app, @y4_changed, true);
            app.x5 = uieditfield(app.OptimizationProblem, 'numeric');
            app.x5.ValueChangedFcn = createCallbackFcn(app, @x5_changed, true);
            app.y5 = uieditfield(app.OptimizationProblem, 'numeric');
            app.y5.ValueChangedFcn = createCallbackFcn(app, @y5_changed, true);
            app.x6 = uieditfield(app.OptimizationProblem, 'numeric');
            app.x6.ValueChangedFcn = createCallbackFcn(app, @x6_changed, true);
            app.y6 = uieditfield(app.OptimizationProblem, 'numeric');
            app.y6.ValueChangedFcn = createCallbackFcn(app, @y6_changed, true);
            app.x7 = uieditfield(app.OptimizationProblem, 'numeric');
            app.x7.ValueChangedFcn = createCallbackFcn(app, @x7_changed, true);
            app.y7 = uieditfield(app.OptimizationProblem, 'numeric');
            app.y7.ValueChangedFcn = createCallbackFcn(app, @y7_changed, true);
            app.eqn_message = uihtml(app.OptimizationProblem);
            app.eqn_message.HTMLSource = '<h3 style="color:#c22317">Enter a valid equation</h3>';
            app.eqn_message.Visible = 'off';
            app.footer = uilabel(app.figure1);
            app.footer.Tag = 'text9';
            app.footer.Text = 'Written by JAMES ANDERSON | Summer 2024';
            app.reset = uibutton(app.figure1, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Tag = 'pushbutton7';
            app.reset.Text = 'RESET';
            app.previous = uibutton(app.figure1, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previous.Tag = 'pushbutton8';
            app.previous.Text = 'PREVIOUS STEP';
            app.next = uibutton(app.figure1, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.next.Tag = 'pushbutton9';
            app.next.Text = 'NEXT STEP';
            app.note = uilabel(app.figure1);
            app.note.Tag = 'text5';
            app.note.Text = 'Note: The Lagrange Multipliers on the right are plotted in colors that match the colors of their corresponding constraints in the f(x,y) contour above. ';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AL_Visualizer_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)PolygonFit_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end